classdef HUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PlaySimulationButton  matlab.ui.control.Button
        UIOxygenAxes          matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlaySimulationButton
        function PlaySimulationButtonPushed(app, event)
            % When play simulation button is pushed, read in HUDFootage and
            % display it within our figure (UIAxes)
            filename = 'data/HUDFootage.mp4';
            v = VideoReader(filename);
            frame = readFrame(v);
            im_frame = imshow(frame, 'Parent', app.UIAxes);

            % loop through entire video length
            while hasFrame(v)
                im_frame.CData = readFrame(v);
                % Draw video frame onto axes
                drawnow

                % Get current frame (ie current data column index)
                currentFrame = v.CurrentTime*v.FrameRate;
                % Display current value in UI axes
                %rectangle('Position',[1 2 5 6])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIOxygenAxes = uiaxes(app.UIFigure);
            title(app.UIOxygenAxes, 'Oxygen Level')
            zlabel(app.UIOxygenAxes, 'Z')
            app.UIOxygenAxes.XTick = [];
            app.UIOxygenAxes.YTick = [0 0.5 1];
            app.UIOxygenAxes.YTickLabel = {'0'; '50%'; '100%'};
            app.PlaySimulationButton = uibutton(app.UIFigure, 'push');
            app.PlaySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySimulationButtonPushed, true);
            app.PlaySimulationButton.Text = 'Play Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HUD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end